classdef HFStimApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        CertaintyEditField           matlab.ui.control.NumericEditField
        CertaintyLabel               matlab.ui.control.Label
        LightoffHzEditField          matlab.ui.control.NumericEditField
        LightoffHzEditFieldLabel     matlab.ui.control.Label
        LightonHzEditField           matlab.ui.control.NumericEditField
        LightonHzEditFieldLabel      matlab.ui.control.Label
        LightoffsetHzEditField       matlab.ui.control.NumericEditField
        LightoffsetHzEditFieldLabel  matlab.ui.control.Label
        LightonsetHzEditField        matlab.ui.control.NumericEditField
        FiringatlightonsetHzLabel    matlab.ui.control.Label
        CutoffSpinner                matlab.ui.control.Spinner
        CutoffSpinnerLabel           matlab.ui.control.Label
        EstimPathEditField           matlab.ui.control.EditField
        EstimPathEditFieldLabel      matlab.ui.control.Label
        NeuronautomaticallyclassifiedasEditField  matlab.ui.control.EditField
        NeuronautomaticallyclassifiedasEditFieldLabel  matlab.ui.control.Label
        LEDdurationmsEditField_2     matlab.ui.control.NumericEditField
        DurationmsEditFieldLabel_2   matlab.ui.control.Label
        ELECdurationmsEditField_2    matlab.ui.control.NumericEditField
        ELECdurationmsEditField      matlab.ui.control.NumericEditField
        LEDdurationmsEditField       matlab.ui.control.NumericEditField
        StimulusendLabel             matlab.ui.control.Label
        ESTIMLabel                   matlab.ui.control.Label
        LEDLabel                     matlab.ui.control.Label
        ESTIMstartEditField          matlab.ui.control.NumericEditField
        LEDstimstartEditField        matlab.ui.control.NumericEditField
        StimulusstartLabel           matlab.ui.control.Label
        windowsizemsEditField        matlab.ui.control.NumericEditField
        windowsizemsEditFieldLabel   matlab.ui.control.Label
        prespikewindowmsEditField    matlab.ui.control.NumericEditField
        prespikemsLabel              matlab.ui.control.Label
        Threshold                    matlab.ui.control.Slider
        ThresholdLabel               matlab.ui.control.Label
        DetectSpikesButton           matlab.ui.control.Button
        TrialSpinner                 matlab.ui.control.Spinner
        TrialSpinnerLabel            matlab.ui.control.Label
        FetchDataButton              matlab.ui.control.Button
        DataPathEditField            matlab.ui.control.EditField
        DataPathEditFieldLabel       matlab.ui.control.Label
        SDKPathEditField             matlab.ui.control.EditField
        SDKPathEditFieldLabel        matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        STIM_UIAxes                  matlab.ui.control.UIAxes
        UIAxes5                      matlab.ui.control.UIAxes
        UIAxes4                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = public)
        data % Description
        ESTIMdur = 0.300;
        currenttrace
        tf
        truepklocs
        spikesfound
        spikewaveforms
        meanspikewaveform
    end

    methods (Access = private)

        function refreshplot(app, ydata)

            cla(app.UIAxes)
            app.tf = 1/app.data.fs:1/app.data.fs:length(ydata)/app.data.fs;
            plot(app.UIAxes, app.tf, ydata, Color=[0.4940 0.1840 0.5560]);
            app.UIAxes.XLim = [0,app.tf(end)];
            app.STIM_UIAxes.XLim = [0,app.tf(end)];
            if app.spikesfound
                drawspikes(app)
            end
        end

        function drawspikes(app)
            if app.TrialSpinner.Value == 0
                hold(app.UIAxes,'on')
                plot(app.UIAxes,app.tf(app.truepklocs),ones(size(app.truepklocs))*10,'rv')
            else
                n = app.truepklocs>app.data.trialsstartindex{app.TrialSpinner.Value};
                m = app.truepklocs<app.data.trialsendindex{app.TrialSpinner.Value};
                temppklocs = app.truepklocs(n&m);
                hold(app.UIAxes,'on');
                plot(app.UIAxes,app.tf(temppklocs-app.data.trialsstartindex{app.TrialSpinner.Value}),ones(size(temppklocs))*10,'rv');
            end
        end

        function detectspikes(app)


            app.CertaintyEditField.Visible = 1;
            app.CertaintyLabel.Visible = 1;
            app.LightoffHzEditField.Visible = 1;
            app.LightoffHzEditFieldLabel.Visible = 1;
            app.LightonHzEditField.Visible = 1;
            app.LightonHzEditFieldLabel.Visible = 1;
            app.LightoffsetHzEditField.Visible = 1;
            app.LightoffsetHzEditFieldLabel.Visible = 1;
            app.LightonsetHzEditField.Visible = 1;
            app.FiringatlightonsetHzLabel.Visible = 1;
            % Find all spikes
            [~, pklocs] = findpeaks(app.data.fulltrace);
            POTs = app.data.fulltrace(pklocs)> app.Threshold.Value;
            app.truepklocs= pklocs(POTs);

            app.spikesfound = 1;
            drawspikes(app)

            % Find spike waveforms within given window size

            spikewaveformss = [];
            startindex = find(app.data.tf>app.prespikewindowmsEditField.Value/1000,1);
            endindex = find(app.data.tf>app.windowsizemsEditField.Value/1000,1);

            if ~isempty(app.truepklocs)
                for i = 1:length(app.truepklocs)
                    if app.truepklocs(i)-startindex>0 && app.truepklocs(i)-startindex+endindex<length(app.data.fulltrace)
                        newspike = app.data.fulltrace(app.truepklocs(i)-startindex:app.truepklocs(i)-startindex+endindex);
                        spikewaveformss = [spikewaveformss;newspike];
                    end
                end
            end

            app.spikewaveforms = spikewaveformss;
            app.meanspikewaveform = mean(app.spikewaveforms);
            timeframe = linspace(-app.prespikewindowmsEditField.Value, app.windowsizemsEditField.Value-app.prespikewindowmsEditField.Value, length(app.meanspikewaveform));
            hold(app.UIAxes3,'off')
            % Plot all spikes
            for i = 1:size(app.spikewaveforms, 1)
                if length(timeframe)==length(app.spikewaveforms(i,:))
                    plot(app.UIAxes3, timeframe, app.spikewaveforms(i,:), Color=[0.4940 0.1840 0.5560])
                end
                hold(app.UIAxes3,'on')
            end
            % Plot meanspike
            plot(app.UIAxes3, timeframe, app.meanspikewaveform, LineWidth=3, Color=	[1 0 0]);
            xlim(app.UIAxes3, [timeframe(1),timeframe(end)])


            % Classify ON-T, ON-S, OFF-T, and OFF-S
            % ON-S responds LED ON>OFF
            % OFF-S responds LED OFF>ON
            % ON-T responds first 33% LED ON > all other times
            % OFF-T responds first 33% LED OFF > all other times

            value = app.ESTIMstartEditField.Value;
            if value < 0
                % Cut off values at front and add zeros or remove values
                % to the end to match trial number
                app.data.fixeduA = app.data.uA(-value+1:end);
                app.data.fixedESTIMkHz = app.data.ESTIM_kHz(-value+1:end);
            elseif value >0
                % Add values at front and add zeros or remove values
                % from the end to match trial number
                app.data.fixeduA = [zeros(value-1,1); app.data.uA];
                app.data.fixedESTIMkHz = [zeros(value-1,1); app.data.ESTIM_kHz];
            else
                app.data.fixeduA = app.data.uA;
                app.data.fixedESTIMkHz = app.data.ESTIM_kHz;
            end

            if length(app.data.fixeduA)>length(app.data.trials)
                app.data.fixeduA = app.data.fixeduA(1:length(app.data.trials));
                app.data.fixedESTIMkHz = app.data.fixedESTIMkHz(1:length(app.data.trials));
            elseif length(app.data.fixeduA)<length(app.data.trials)
                app.data.fixeduA = [app.data.fixeduA,zeros(length(app.data.trials)-length(app.data.fixeduA),1)];
                app.data.fixedESTIMkHz = [app.data.fixedESTIMkHz,zeros(length(app.data.trials)-length(app.data.fixedESTIMkHz),1)];
            end

            value = app.LEDstimstartEditField.Value;

            if value < 0
                % Cut off values at front and add zeros or remove values
                % to the end to match trial number
                app.data.fixedLEDintensity = app.data.LEDintensity(-value+1:end);
            elseif value >0
                % Add values at front and add zeros or remove values
                % from the end to match trial number
                app.data.fixedLEDintensity = [zeros(value-1,1); app.data.LEDintensity];
            else
                app.data.fixedLEDintensity = app.data.LEDintensity;
            end

            if length(app.data.fixedLEDintensity)>length(app.data.trials)
                app.data.fixedLEDintensity = app.data.fixedLEDintensity(1:length(app.data.trials));
            elseif length(app.data.fixedLEDintensity)<length(app.data.trials)
                app.data.fixedLEDintensity = [app.data.fixedLEDintensity,zeros(length(app.data.trials)-length(app.data.fixedLEDintensity),1)];
            end

            % Create
            app.data.trialsLED = cell(size(app.data.trials));
            app.data.trialsESTIM = cell(size(app.data.trials));
            app.data.ESTIMfulltrace = [];
            app.data.LEDfulltrace = [];

            for i = 1:length(app.data.trials)
                indexesLED = zeros(size(app.data.trials{1}));
                if app.data.LEDintensity(i)>0
                    indexesLED(1:find(app.data.tf>app.LEDdurationmsEditField_2.Value/1000,1))=app.data.LEDintensity(i);
                end
                app.data.trialsLED{i}=indexesLED;
                app.data.LEDfulltrace = [app.data.LEDfulltrace,indexesLED];

                indexesESTIM = zeros(size(app.data.trials{1}));
                if app.data.LEDintensity(i)>0
                    indexesESTIM(1:find(app.data.tf>app.ELECdurationmsEditField_2.Value/1000,1)) = app.data.fixeduA(i);
                end
                app.data.trialsESTIM{i} = indexesESTIM;
                app.data.ESTIMfulltrace = [app.data.ESTIMfulltrace,indexesESTIM];
            end

            value = app.TrialSpinner.Value;
            if value == 0
                title(app.UIAxes,'Full trace')
                refreshplot(app, app.data.fulltrace);
                app.currenttrace = app.data.fulltrace;
                % Depict Stim
                xdata = 1/app.data.fs:1/app.data.fs:length(app.currenttrace)/app.data.fs;
                yyaxis(app.STIM_UIAxes,'left')
                plot(app.STIM_UIAxes,xdata,app.data.LEDfulltrace(1:length(app.currenttrace)), LineWidth=3, Color=	[1 0 0]);
                if max(app.data.LEDfulltrace(1:length(app.currenttrace)))>0
                    app.STIM_UIAxes.YLim = [0,max(app.data.LEDfulltrace(1:length(app.currenttrace)))];
                end
                yyaxis(app.STIM_UIAxes,'right')
                plot(app.STIM_UIAxes,xdata,app.data.ESTIMfulltrace(1:length(app.currenttrace)), LineWidth=3, Color=	[0 1 0]);
                if max(app.data.ESTIMfulltrace(1:length(app.currenttrace)))>0
                    app.STIM_UIAxes.YLim = [0,max(app.data.ESTIMfulltrace(1:length(app.currenttrace)))];
                end
            else
                stringy = strcat({'Trial no. '},num2str(value));
                title(app.UIAxes,stringy)
                refreshplot(app, app.data.trials{value});
                app.currenttrace = app.data.trials{value};
                % Depict Stim
                xdata = 1/app.data.fs:1/app.data.fs:length(app.currenttrace)/app.data.fs;
                yyaxis(app.STIM_UIAxes,'left')
                plot(app.STIM_UIAxes,xdata,app.data.trialsLED{value}, LineWidth=3, Color=	[1 0 0]);
                if max(app.data.LEDfulltrace(1:length(app.currenttrace)))>0
                    app.STIM_UIAxes.YLim = [0,max(app.data.LEDfulltrace(1:length(app.currenttrace)))];
                end
                yyaxis(app.STIM_UIAxes,'right')
                plot(app.STIM_UIAxes,xdata,app.data.trialsESTIM{value}, LineWidth=3, Color=	[0 1 0]);
                if max(app.data.ESTIMfulltrace(1:length(app.currenttrace)))>0
                    app.STIM_UIAxes.YLim = [0,max(app.data.ESTIMfulltrace(1:length(app.currenttrace)))];
                end
            end
            threshold = app.Threshold.Value;
            hold(app.UIAxes,'on')
            plot(app.UIAxes, [1/app.data.fs, length(app.currenttrace)/app.data.fs], [threshold,threshold], Color=[0.3010 0.7450 0.9330]);

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TrialSpinner.Visible = 0;
            app.DetectSpikesButton.Visible = 0;
            app.Threshold.Visible = 0;
            app.spikesfound = 0;
            title(app.UIAxes,'Full trace')

            app.TrialSpinnerLabel.Visible = 0;
            app.ThresholdLabel.Visible = 0;
            app.prespikewindowmsEditField.Visible = 0;
            app.prespikemsLabel.Visible = 0;
            app.windowsizemsEditField.Visible = 0;
            app.windowsizemsEditFieldLabel.Visible = 0;
            app.LEDLabel.Visible = 0;
            app.ESTIMLabel.Visible = 0;
            app.LEDstimstartEditField.Visible = 0;
            app.StimulusstartLabel.Visible = 0;
            app.ESTIMstartEditField.Visible = 0;
            app.LEDdurationmsEditField.Visible = 0;
            app.StimulusendLabel.Visible = 0;
            app.LEDdurationmsEditField_2.Visible = 0;
            app.DurationmsEditFieldLabel_2.Visible = 0;
            app.ELECdurationmsEditField.Visible = 0;
            app.ELECdurationmsEditField_2.Visible = 0;
            app.NeuronautomaticallyclassifiedasEditField.Visible = 0;
            app.NeuronautomaticallyclassifiedasEditFieldLabel.Visible = 0;
            app.UIAxes5.Visible = 0;
            app.CutoffSpinner.Visible = 0;
            app.CutoffSpinnerLabel.Visible = 0;


            app.CertaintyEditField.Visible = 0;
            app.CertaintyLabel.Visible = 0;
            app.LightoffHzEditField.Visible = 0;
            app.LightoffHzEditFieldLabel.Visible = 0;
            app.LightonHzEditField.Visible = 0;
            app.LightonHzEditFieldLabel.Visible = 0;
            app.LightoffsetHzEditField.Visible = 0;
            app.LightoffsetHzEditFieldLabel.Visible = 0;
            app.LightonsetHzEditField.Visible = 0;
            app.FiringatlightonsetHzLabel.Visible = 0;
        end

        % Button pushed function: FetchDataButton
        function FetchDataButtonPushed(app, event)
            addpath(genpath(app.SDKPathEditField.Value));
            app.data = TDTbin2mat(app.DataPathEditField.Value);
            app.data.ESTIM = readtable(app.EstimPathEditField.Value);
            app.data.uA = app.data.ESTIM.ch1_curr_p2(2:end);
            estim_ms = app.ELECdurationmsEditField_2.Value/1000;
            app.data.ESTIM_kHz = app.data.ESTIM.pulses(2:end)/estim_ms/1000;

            app.data.fulltrace = app.data.streams.SRAW.data*100;
            app.data.backupfulltrace = app.data.streams.SRAW.data*100;
            app.data.fs = app.data.streams.SRAW.fs;
            app.data.tf = 1/app.data.fs:1/app.data.fs:size(app.data.fulltrace,2)/app.data.fs;
            app.data.trigger = app.data.epocs.STIM.onset;
            app.data.LEDintensity = app.data.epocs.LEDO.data;
            app.data.LEDdur = app.data.epocs.LDUR.data;
            app.data.blockname = app.data.info.blockname;
            app.data.date = app.data.info.date;

            refreshplot(app,app.data.fulltrace)
            threshold = app.Threshold.Value;
            title(app.UIAxes,'Full trace')
            hold(app.UIAxes,'on')
            plot(app.UIAxes, [1/app.data.fs, length(app.currenttrace)/app.data.fs], [threshold,threshold], Color=[0.3010 0.7450 0.9330]);


            app.currenttrace = app.data.fulltrace;

            app.TrialSpinner.Limits = [0,length(app.data.trigger)];
            app.CutoffSpinner.Limits = [2,length(app.data.trigger)+1];
            app.CutoffSpinner.Value = length(app.data.trigger)+1;

            %Make things visible again
            app.TrialSpinner.Visible = 1;
            app.DetectSpikesButton.Visible = 1;
            app.Threshold.Visible = 1;
            app.CutoffSpinner.Visible = 1;
            app.CutoffSpinnerLabel.Visible = 1;
            app.prespikewindowmsEditField.Visible = 1;
            app.prespikemsLabel.Visible = 1;
            app.windowsizemsEditField.Visible = 1;
            app.windowsizemsEditFieldLabel.Visible = 1;
            app.LEDLabel.Visible = 1;
            app.ESTIMLabel.Visible = 1;
            app.LEDstimstartEditField.Visible = 1;
            app.StimulusstartLabel.Visible = 1;
            app.ESTIMstartEditField.Visible = 1;
            app.LEDdurationmsEditField.Visible = 1;
            app.StimulusendLabel.Visible = 1;
            app.LEDdurationmsEditField_2.Visible = 1;
            app.DurationmsEditFieldLabel_2.Visible = 1;
            app.ELECdurationmsEditField.Visible = 1;
            app.ELECdurationmsEditField_2.Visible = 1;
            app.NeuronautomaticallyclassifiedasEditField.Visible = 1;
            app.NeuronautomaticallyclassifiedasEditFieldLabel.Visible = 1;
            app.UIAxes5.Visible = 1;

            %Split data into trials
            trials = cell(1,length(app.data.trigger));
            trialsstartindex = cell(1,length(app.data.trigger));
            trialsendindex = cell(1,length(app.data.trigger));
            for i = 1:length(app.data.trigger)
                startindex = find(app.data.tf>=app.data.trigger(i),1);
                if i == length(app.data.trigger)
                    endindex = length(app.data.fulltrace);
                else
                    endindex = find(app.data.tf>=app.data.trigger(i+1),1);
                end
                trials{i} = app.data.fulltrace(startindex:endindex);
                trialsstartindex{i} = startindex;
                trialsendindex{i} = endindex;
            end
            app.data.trials = trials';
            app.data.trialsstartindex = trialsstartindex;
            app.data.trialsendindex = trialsendindex;


            app.TrialSpinnerLabel.Visible = 1;
            app.ThresholdLabel.Visible = 1;


            % Automatically detect spikes

            value = app.ESTIMstartEditField.Value;
            if value < 0
                % Cut off values at front and add zeros or remove values
                % to the end to match trial number
                app.data.fixeduA = app.data.uA(-value+1:end);
                app.data.fixedESTIMkHz = app.data.ESTIM_kHz(-value+1:end);
            elseif value >0
                % Add values at front and add zeros or remove values
                % from the end to match trial number
                app.data.fixeduA = [zeros(value-1,1); app.data.uA];
                app.data.fixedESTIMkHz = [zeros(value-1,1); app.data.ESTIM_kHz];
            else
                app.data.fixeduA = app.data.uA;
                app.data.fixedESTIMkHz = app.data.ESTIM_kHz;
            end

            if length(app.data.fixeduA)>length(app.data.trials)
                app.data.fixeduA = app.data.fixeduA(1:length(app.data.trials));
                app.data.fixedESTIMkHz = app.data.fixedESTIMkHz(1:length(app.data.trials));
            elseif length(app.data.fixeduA)<length(app.data.trials)
                app.data.fixeduA = [app.data.fixeduA,zeros(length(app.data.trials)-length(app.data.fixeduA),1)];
                app.data.fixedESTIMkHz = [app.data.fixedESTIMkHz,zeros(length(app.data.trials)-length(app.data.fixedESTIMkHz),1)];
            end

            value = app.LEDstimstartEditField.Value;

            if value < 0
                % Cut off values at front and add zeros or remove values
                % to the end to match trial number
                app.data.fixedLEDintensity = app.data.LEDintensity(-value+1:end);
            elseif value >0
                % Add values at front and add zeros or remove values
                % from the end to match trial number
                app.data.fixedLEDintensity = [zeros(value-1,1); app.data.LEDintensity];
            else
                app.data.fixedLEDintensity = app.data.LEDintensity;
            end

            if length(app.data.fixedLEDintensity)>length(app.data.trials)
                app.data.fixedLEDintensity = app.data.fixedLEDintensity(1:length(app.data.trials));
            elseif length(app.data.fixedLEDintensity)<length(app.data.trials)
                app.data.fixedLEDintensity = [app.data.fixedLEDintensity,zeros(length(app.data.trials)-length(app.data.fixedLEDintensity),1)];
            end
            app.data.trialsLED = cell(size(app.data.trials));
            app.data.trialsESTIM = cell(size(app.data.trials));
            app.data.ESTIMfulltrace = [];
            app.data.LEDfulltrace = [];

            for i = 1:length(app.data.trials)
                indexesLED = zeros(size(app.data.trials{1}));
                if app.data.LEDintensity(i)>0
                    indexesLED(1:find(app.data.tf>app.LEDdurationmsEditField_2.Value/1000,1))=app.data.LEDintensity(i);
                end
                app.data.trialsLED{i}=indexesLED;
                app.data.LEDfulltrace = [app.data.LEDfulltrace,indexesLED];

                indexesESTIM = zeros(size(app.data.trials{1}));
                if app.data.LEDintensity(i)>0
                    indexesESTIM(1:find(app.data.tf>app.ELECdurationmsEditField_2.Value/1000,1)) = app.data.fixeduA(i);
                end
                app.data.trialsESTIM{i} = indexesESTIM;
                app.data.ESTIMfulltrace = [app.data.ESTIMfulltrace,indexesESTIM];
            end
%             detectspikes(app)
        end

        % Value changed function: TrialSpinner
        function TrialSpinnerValueChanged(app, event)
            value = app.TrialSpinner.Value;
            if value == 0
                title(app.UIAxes,'Full trace')
                refreshplot(app, app.data.fulltrace);
                app.currenttrace = app.data.fulltrace;
                % Depict Stim
                xdata = 1/app.data.fs:1/app.data.fs:length(app.currenttrace)/app.data.fs;
                yyaxis(app.STIM_UIAxes,'left')
                plot(app.STIM_UIAxes,xdata,app.data.LEDfulltrace(1:length(app.currenttrace)));
                app.STIM_UIAxes.YLim = [0,max(app.data.LEDfulltrace(1:length(app.currenttrace)))];
                yyaxis(app.STIM_UIAxes,'right')
                plot(app.STIM_UIAxes,xdata,app.data.ESTIMfulltrace(1:length(app.currenttrace)));
                app.STIM_UIAxes.YLim = [0,max(app.data.ESTIMfulltrace(1:length(app.currenttrace)))];
            else
                stringy = strcat({'Trial no. '},num2str(value));
                title(app.UIAxes,stringy)
                refreshplot(app, app.data.trials{value});
                app.currenttrace = app.data.trials{value};
                % Depict Stim
                xdata = 1/app.data.fs:1/app.data.fs:length(app.currenttrace)/app.data.fs;
                yyaxis(app.STIM_UIAxes,'left')
                plot(app.STIM_UIAxes,xdata,app.data.trialsLED{value});
                app.STIM_UIAxes.YLim = [0,max(app.data.LEDfulltrace(1:length(app.currenttrace)))];
                yyaxis(app.STIM_UIAxes,'right')
                plot(app.STIM_UIAxes,xdata,app.data.trialsESTIM{value});
                app.STIM_UIAxes.YLim = [0,max(app.data.ESTIMfulltrace(1:length(app.currenttrace)))];
            end
            threshold = app.Threshold.Value;
            hold(app.UIAxes,'on')
            plot(app.UIAxes, [1/app.data.fs, length(app.currenttrace)/app.data.fs], [threshold,threshold], Color=[0.3010 0.7450 0.9330]);


        end

        % Button pushed function: DetectSpikesButton
        function DetectSpikesButtonPushed(app, event)
            detectspikes(app)


        end

        % Value changing function: Threshold
        function ThresholdValueChanging(app, event)
            changingValue = event.Value;
            refreshplot(app,app.currenttrace)
            hold(app.UIAxes,'on')
            plot(app.UIAxes, [1/app.data.fs, length(app.currenttrace)/app.data.fs], [changingValue,changingValue], Color=[0.3010 0.7450 0.9330]);
        end

        % Value changed function: Threshold
        function ThresholdValueChanged(app, event)
            value = app.Threshold.Value;
            refreshplot(app,app.currenttrace)
            hold(app.UIAxes,'on')
            plot(app.UIAxes, [1/app.data.fs, length(app.currenttrace)/app.data.fs], [value,value], Color=[0.3010 0.7450 0.9330]);
        end

        % Value changed function: CutoffSpinner
        function CutoffSpinnerValueChanged(app, event)
            value = app.CutoffSpinner.Value;

            app.spikewaveforms = [];
            app.meanspikewaveform = [];
            app.truepklocs = [];

            if value<length(app.data.trigger)+1
                app.data.fulltrace = app.data.backupfulltrace(1:app.data.trialsstartindex{value}-1);
            else
                app.data.fulltrace = app.data.backupfulltrace;
            end
            app.TrialSpinner.Limits = [0,value-1];

            if app.TrialSpinner.Value > value
                app.TrialSpinner.Value = value-1;
            end
            app.currenttrace = app.data.fulltrace;

            if app.TrialSpinner.Value == 0
                title(app.UIAxes,'Full trace')
                refreshplot(app, app.data.fulltrace);
                app.currenttrace = app.data.fulltrace;
            else
                stringy = strcat({'Trial no. '},num2str(app.TrialSpinner.Value));
                title(app.UIAxes,stringy)
                refreshplot(app, app.data.trials{app.TrialSpinner.Value});
                app.currenttrace = app.data.trials{app.TrialSpinner.Value};
            end
            threshold = app.Threshold.Value;
            hold(app.UIAxes,'on')
            plot(app.UIAxes, [1/app.data.fs, length(app.currenttrace)/app.data.fs], [threshold,threshold], Color=[0.3010 0.7450 0.9330]);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {609, 609};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SDKPathEditFieldLabel = uilabel(app.LeftPanel);
            app.SDKPathEditFieldLabel.Text = 'SDK Path';
            app.SDKPathEditField = uieditfield(app.LeftPanel, 'text');
            app.SDKPathEditField.Value = 'C:\Intel\TDT\TDTMatlabSDK\TDTSDK';
            app.DataPathEditFieldLabel = uilabel(app.LeftPanel);
            app.DataPathEditFieldLabel.Text = 'Data Path';
            app.DataPathEditField = uieditfield(app.LeftPanel, 'text');
            app.DataPathEditField.Value = 'C:\Intel\Cortical data\HR002_2\HR002_2\Pip1-24';
            app.FetchDataButton = uibutton(app.LeftPanel, 'push');
            app.FetchDataButton.ButtonPushedFcn = createCallbackFcn(app, @FetchDataButtonPushed, true);
            app.FetchDataButton.Text = 'Fetch Data';
            app.TrialSpinnerLabel = uilabel(app.LeftPanel);
            app.TrialSpinnerLabel.Text = 'Trial';
            app.TrialSpinner = uispinner(app.LeftPanel);
            app.TrialSpinner.ValueChangedFcn = createCallbackFcn(app, @TrialSpinnerValueChanged, true);
            app.DetectSpikesButton = uibutton(app.LeftPanel, 'push');
            app.DetectSpikesButton.ButtonPushedFcn = createCallbackFcn(app, @DetectSpikesButtonPushed, true);
            app.DetectSpikesButton.Text = 'Detect Spikes';
            app.ThresholdLabel = uilabel(app.LeftPanel);
            app.ThresholdLabel.Text = 'Threshold';
            app.Threshold = uislider(app.LeftPanel);
            app.Threshold.Limits = [-50 10];
            app.Threshold.ValueChangedFcn = createCallbackFcn(app, @ThresholdValueChanged, true);
            app.Threshold.ValueChangingFcn = createCallbackFcn(app, @ThresholdValueChanging, true);
            app.Threshold.Value = -15;
            app.prespikemsLabel = uilabel(app.LeftPanel);
            app.prespikemsLabel.Text = 'pre spike window [ms]';
            app.prespikewindowmsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.prespikewindowmsEditField.Value = 5;
            app.windowsizemsEditFieldLabel = uilabel(app.LeftPanel);
            app.windowsizemsEditFieldLabel.Text = 'window size [ms]';
            app.windowsizemsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.windowsizemsEditField.Limits = [0 Inf];
            app.windowsizemsEditField.Value = 10;
            app.StimulusstartLabel = uilabel(app.LeftPanel);
            app.StimulusstartLabel.Text = 'Stimulus start';
            app.LEDstimstartEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ESTIMstartEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LEDLabel = uilabel(app.LeftPanel);
            app.LEDLabel.Text = 'LED';
            app.ESTIMLabel = uilabel(app.LeftPanel);
            app.ESTIMLabel.Text = 'ESTIM';
            app.StimulusendLabel = uilabel(app.LeftPanel);
            app.StimulusendLabel.Text = 'Stimulus end';
            app.LEDdurationmsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LEDdurationmsEditField.Value = -1;
            app.ELECdurationmsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ELECdurationmsEditField.Value = -1;
            app.ELECdurationmsEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.ELECdurationmsEditField_2.Value = 300;
            app.DurationmsEditFieldLabel_2 = uilabel(app.LeftPanel);
            app.DurationmsEditFieldLabel_2.Text = 'Duration [ms]';
            app.LEDdurationmsEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.LEDdurationmsEditField_2.Value = 500;
            app.NeuronautomaticallyclassifiedasEditFieldLabel = uilabel(app.LeftPanel);
            app.NeuronautomaticallyclassifiedasEditFieldLabel.Text = 'Neuron automatically classified as:';
            app.NeuronautomaticallyclassifiedasEditField = uieditfield(app.LeftPanel, 'text');
            app.NeuronautomaticallyclassifiedasEditField.Editable = 'off';
            app.EstimPathEditFieldLabel = uilabel(app.LeftPanel);
            app.EstimPathEditFieldLabel.Text = 'Estim Path';
            app.EstimPathEditField = uieditfield(app.LeftPanel, 'text');
            app.EstimPathEditField.Value = 'C:\Intel\Cortical data\S2_1-0_75-6_25kH-at-0-100-1000uA-voltages27-04-2022 at 0740.csv';
            app.CutoffSpinnerLabel = uilabel(app.LeftPanel);
            app.CutoffSpinnerLabel.Text = 'Cutoff';
            app.CutoffSpinner = uispinner(app.LeftPanel);
            app.CutoffSpinner.ValueChangedFcn = createCallbackFcn(app, @CutoffSpinnerValueChanged, true);
            app.FiringatlightonsetHzLabel = uilabel(app.LeftPanel);
            app.FiringatlightonsetHzLabel.Text = 'Light onset                Hz';
            app.LightonsetHzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LightoffsetHzEditFieldLabel = uilabel(app.LeftPanel);
            app.LightoffsetHzEditFieldLabel.Text = 'Light offset                Hz';
            app.LightoffsetHzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LightonHzEditFieldLabel = uilabel(app.LeftPanel);
            app.LightonHzEditFieldLabel.Text = 'Light on                Hz';
            app.LightonHzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LightoffHzEditFieldLabel = uilabel(app.LeftPanel);
            app.LightoffHzEditFieldLabel.Text = 'Light off                Hz';
            app.LightoffHzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CertaintyLabel = uilabel(app.LeftPanel);
            app.CertaintyLabel.Text = 'Certainty             %';
            app.CertaintyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, 'Voltage [V]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Raster')
            xlabel(app.UIAxes2, 'Time [s]')
            ylabel(app.UIAxes2, 'Trial')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Spikes')
            xlabel(app.UIAxes3, 'Time [ms]')
            ylabel(app.UIAxes3, 'Voltage [V]')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.RightPanel);
            title(app.UIAxes4, 'Heatmap')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.RightPanel);
            title(app.UIAxes5, 'Neuron response')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.STIM_UIAxes = uiaxes(app.RightPanel);
            title(app.STIM_UIAxes, 'Stim')
            xlabel(app.STIM_UIAxes, 'Time [s]')
            ylabel(app.STIM_UIAxes, 'Y')
            zlabel(app.STIM_UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HFStimApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end